classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EjecutarButton                  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        IngreselossiguientesdatosLabel  matlab.ui.control.Label
        CurvadecargaLabel               matlab.ui.control.Label
        tabla_CurvaDeCarga              matlab.ui.control.Table
        readisponiblem2Label            matlab.ui.control.Label
        readisponiblem2EditField        matlab.ui.control.NumericEditField
        LatitudEditFieldLabel           matlab.ui.control.Label
        LatitudEditField                matlab.ui.control.NumericEditField
        CargarCheckBox                  matlab.ui.control.CheckBox
        LongitudEditFieldLabel          matlab.ui.control.Label
        LongitudEditField               matlab.ui.control.NumericEditField
        AlturadelbujeEditFieldLabel     matlab.ui.control.Label
        AlturadelbujeEditField          matlab.ui.control.NumericEditField
    end

    
    methods (Access = public)
        
        function results = func(app)
            
            app.tabla_CurvaDeCarga.Data=zeros(24,1);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CargarCheckBox, tabla_CurvaDeCarga
        function tabla_CurvaDeCargaCellEdit(app, event)

            if isempty(app.tabla_CurvaDeCarga.Data)
                aspc=datetime('now','Format','d-MMM-y ');
                [~,~,~,~,~,~,~,potencia_requerida]=cargaExcel();
                %inputs=table([(1:length(potencia_requerida))',potencia_requerida]);
                horas=(1:length(potencia_requerida))';
                input=table(horas,potencia_requerida);
                app.tabla_CurvaDeCarga.Data=input;
                %app.tabla_CurvaDeCarga.RowName='numbered';
            end
            %Aquí se incluye la parte que carga los valores%
            [areaL,~,~,turbina,~,~,~,~]=cargaExcel();
            app.AlturadelbujeEditField.Value=turbina.alturaUsada;
            app.readisponiblem2EditField.Value=areaL;
            %Aqui muestra si la columna fue editada%
            app.tabla_CurvaDeCarga.ColumnEditable=[false,true];
            messageError="Cerrar el archivo ";
            nameFile="test_curva_carga.xlsx";
            try
                %Aqui edita la tabla"
                writetable(app.tabla_CurvaDeCarga.Data,nameFile,'Sheet',"RecursoRenovable",...
                    "Range","A3:A26");
            catch
                errordlg(strcat(messageError,nameFile ),"CERRAR EXCEL");
                close(app.UIFigure);
            end
 
        end

        % Cell selection callback: tabla_CurvaDeCarga
        function tabla_CurvaDeCargaCellSelection(app, event)
            indices = event.Indices;
            
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            messageError="Cerrar el archivo ";
            nameFile="test_curva_carga.xlsx";
            try
                %Edita la tabla, incluyendo el valor de área disponible
                writematrix(app.readisponiblem2EditField.Value,"Entradas.xlsx",'Sheet','input',"Range","B2");
                %Edita la tabla, incluyendo el valor de área disponible
                writematrix(app.AlturadelbujeEditField.Value,"Entradas.xlsx",'Sheet','input',...
                    "Range","D16");
            catch
                errordlg(strcat(messageError,nameFile ),"CERRAR EXCEL");
            end
                curva_de_carga=app.tabla_CurvaDeCarga.Data.potencia_requerida;
                altitud_GUI=app.AlturadelbujeEditField.Value;
                areaLibre=app.readisponiblem2EditField.Value;
                [a,pie_chat_IM,t10]=algoritmoGenetico(curva_de_carga,altitud_GUI,areaLibre);
                iface_end(a,pie_chat_IM,t10)

            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EjecutarButton = uibutton(app.UIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Unab Logo.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Herramienta para el dimensionamiento de '; 'Sistemas Hibridos de Energía'};
            app.IngreselossiguientesdatosLabel = uilabel(app.UIFigure);
            app.IngreselossiguientesdatosLabel.Text = {'Para el calculo del sistema hibrido de energia, debe'; ' ingresar los siguientes datos:'};
            app.CurvadecargaLabel = uilabel(app.UIFigure);
            app.CurvadecargaLabel.Text = 'Curva de carga';
            app.tabla_CurvaDeCarga = uitable(app.UIFigure);
            app.tabla_CurvaDeCarga.ColumnName = {'Hora'; 'Potencia [kW]'};
            app.tabla_CurvaDeCarga.RowName = {};
            app.tabla_CurvaDeCarga.ColumnSortable = [false true];
            app.tabla_CurvaDeCarga.ColumnEditable = [false true];
            app.tabla_CurvaDeCarga.CellEditCallback = createCallbackFcn(app, @tabla_CurvaDeCargaCellEdit, true);
            app.tabla_CurvaDeCarga.CellSelectionCallback = createCallbackFcn(app, @tabla_CurvaDeCargaCellSelection, true);
            app.readisponiblem2Label = uilabel(app.UIFigure);
            app.readisponiblem2Label.Text = {'Área '; 'disponible [m^2]'};
            app.readisponiblem2EditField = uieditfield(app.UIFigure, 'numeric');
            app.readisponiblem2EditField.Limits = [0 Inf];
            app.LatitudEditFieldLabel = uilabel(app.UIFigure);
            app.LatitudEditFieldLabel.Text = 'Latitud';
            app.LatitudEditField = uieditfield(app.UIFigure, 'numeric');
            app.CargarCheckBox = uicheckbox(app.UIFigure);
            app.CargarCheckBox.ValueChangedFcn = createCallbackFcn(app, @tabla_CurvaDeCargaCellEdit, true);
            app.CargarCheckBox.Text = 'Cargar';
            app.LongitudEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudEditFieldLabel.Text = 'Longitud';
            app.LongitudEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturadelbujeEditFieldLabel = uilabel(app.UIFigure);
            app.AlturadelbujeEditFieldLabel.Text = 'Altura del buje';
            app.AlturadelbujeEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end